classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MquinaPVIUIFigure  matlab.ui.Figure
        ButtonGroup        matlab.ui.container.ButtonGroup
        EulerButton        matlab.ui.control.RadioButton
        EulerButton_2      matlab.ui.control.RadioButton
        RK2Button          matlab.ui.control.RadioButton
        RK4Button          matlab.ui.control.RadioButton
        ODE45Button        matlab.ui.control.RadioButton
        PMButton           matlab.ui.control.RadioButton
        TodosButton        matlab.ui.control.RadioButton
        LimparButton       matlab.ui.control.Button
        CalcularButton     matlab.ui.control.Button
        MtodosLabel        matlab.ui.control.Label
        func               matlab.ui.control.EditField
        ftyLabel           matlab.ui.control.Label
        DadosdoPVILabel    matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        aEditFieldLabel    matlab.ui.control.Label
        a_field            matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        b_field            matlab.ui.control.NumericEditField
        nEditFieldLabel    matlab.ui.control.Label
        n_field            matlab.ui.control.NumericEditField
        y0EditFieldLabel   matlab.ui.control.Label
        y0_field           matlab.ui.control.NumericEditField
        UITable_2          matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            cla(app.UIAxes); % Limpar o gráfico
            
            syms y(t) % Criar uma função simbólica
            
            %%%%% Verificação do Input %%%%%
            strF = app.func.Value; % Obter o valor no campo da função
            f = @(t,y) eval(vectorize(strF)); % Definir a nossa função
            try % Testar se a função inserida é válida
                f(t,y); 
            catch
                errordlg("Introduza uma função em t e y","Erro");
                return
            end
            
            a = app.a_field.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field.Value; % Obter o valor do campo N
            if (not(isscalar(n) && isreal(n))) % O valor N tem de ser real
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
        
            y0 = app.y0_field.Value; % Obter o valor da condição inicial
            if (not(isscalar(y0) && isreal(y0))) % A condição inicial tem de ser real
                errordlg("O número introduzido para y0 é inválido","Erro");
                return
            end
            
            
            %%%%% Adicionar pasta dos métodos ao PATH caso ainda não tenha sido feito %%%%%
            try
                MEuler(f,a,b,n,y0);
            catch ME
                addpath Métodos
            end
            
            
            %%%%% Escolha do método %%%%%
            if app.EulerButton.Value
                escolha = "Euler";
            elseif app.EulerButton_2.Value
                escolha = "Euler+";
            elseif app.RK2Button.Value
                escolha = "RK2";
            elseif app.RK4Button.Value
                escolha = "RK4";
            elseif app.ODE45Button.Value
                escolha = "ODE45";
            elseif app.PMButton.Value
                escolha = "PM";
            elseif app.TodosButton.Value
                escolha = "Todos";
            end
            
            %%%%% Cálculo da Solução Exata %%%%%
            xExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            g =@(t) eval(vectorize(char(xExata))); % Função simbólica
            h = (b-a)/n; % Tamanho dos subintervalos
            t = a:h:b; % Array com os subintervalos
            yExata = g(t); % Array com os valores de y exatos
            plot(app.UIAxes,yExata,'-r'); % Mostrar o gráfico
            hold(app.UIAxes, "on"); % Segurar o gráfico
            
            
            %%%%% Cálculo da solução a partir de um método %%%%%
            switch escolha
                case "Euler"
                    sol = MEuler(f,a,b,n,y0); % Função do método
                    plot(app.UIAxes,sol,'-b'); % Mostrar o gráfico
                    legend(app.UIAxes,'Exata','Euler'); % Legenda do gráfico
                    hold(app.UIAxes, "on"); % Segurar o gráfico
                    erro = abs(yExata - sol); % Cálculo do erro
                    tabela = [t.',yExata.',sol.', erro.']; % Criação da tabela
                    set(app.UITable, 'columnname', {'t', 'Exata', 'Euler', 'Erro'}); % Nomes das colunas da tabela
                    app.UITable.Data = tabela; % Preencher a tabela com os valores obtidos
                case "Euler+"
                    sol = MEulerMelhorado(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','Euler+');
                    hold(app.UIAxes, "on");
                    erro = abs(yExata - sol);
                    tabela = [t.',yExata.',sol.', erro.'];
                    set(app.UITable, 'columnname', {'t', 'Exata', 'Euler+', 'Erro'});
                    app.UITable.Data = tabela;
                case "RK2"
                    sol = NRK2(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','RK2');
                    hold(app.UIAxes, "on");
                    erro = abs(yExata - sol);
                    tabela = [t.',yExata.',sol.', erro.'];
                    set(app.UITable, 'columnname', {'t', 'Exata', 'RK2', 'Erro'});
                    app.UITable.Data = tabela;
                case "RK4"
                    sol = NRK4(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','RK4');
                    hold(app.UIAxes, "on");
                    erro = abs(yExata - sol);
                    tabela = [t.',yExata.',sol.', erro.'];
                    set(app.UITable, 'columnname', {'t', 'Exata', 'RK4', 'Erro'});
                    app.UITable.Data = tabela;
                case "ODE45"
                    sol = NODE45(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','ODE45');
                    hold(app.UIAxes, "on");
                    erro = abs(yExata - sol);
                    tabela = [t.',yExata.',sol.', erro.'];
                    set(app.UITable, 'columnname', {'t', 'Exata', 'ODE45', 'Erro'});
                    app.UITable.Data = tabela;
                case "PM"
                    sol = NPM(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','PM');
                    hold(app.UIAxes, "on");
                    erro = abs(yExata - sol);
                    tabela = [t.',yExata.',sol.', erro.'];
                    set(app.UITable, 'columnname', {'t', 'Exata', 'PM', 'Erro'});
                    app.UITable.Data = tabela;
                case "Todos"
                    Euler = MEuler(f,a,b,n,y0);
                    EulerM = MEulerMelhorado(f,a,b,n,y0);
                    RK2 = NRK2(f,a,b,n,y0);
                    RK4 = NRK4(f,a,b,n,y0);
                    ODE45 = NODE45(f,a,b,n,y0);
                    PM = NPM(f,a,b,n,y0);
                    
                    tabela = [t.',yExata.',Euler.',EulerM.',RK2.',RK4.',ODE45.',PM.'];
                    set(app.UITable, 'columnname', {'t', 'Exata', 'Euler', 'Euler+','RK2','RK4','ODE45','PM'});
                    app.UITable.Data = tabela;
                    
                    plot(app.UIAxes,Euler);
                    plot(app.UIAxes,EulerM);
                    plot(app.UIAxes,RK2);
                    plot(app.UIAxes,RK4);
                    plot(app.UIAxes,ODE45);
                    plot(app.UIAxes,PM);
                    legend(app.UIAxes,'Exata','Euler','Euler+','RK2','RK4','ODE45','PM');
                    hold(app.UIAxes, "on");
            end

        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
                cla(app.UIAxes);
                app.UITable.Data = {};
                set(app.UITable, 'columnname', {});
                app.y0_field.Value=0;
                app.n_field.Value=0;
                app.b_field.Value=0;
                app.a_field.Value=0;
                app.func.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MquinaPVIUIFigure = uifigure('Visible', 'off');
            app.MquinaPVIUIFigure.Name = 'Máquina PVI';
            app.ButtonGroup = uibuttongroup(app.MquinaPVIUIFigure);
            app.EulerButton = uiradiobutton(app.ButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerButton_2 = uiradiobutton(app.ButtonGroup);
            app.EulerButton_2.Text = 'Euler +';
            app.RK2Button = uiradiobutton(app.ButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uiradiobutton(app.ButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uiradiobutton(app.ButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.PMButton = uiradiobutton(app.ButtonGroup);
            app.PMButton.Text = 'PM';
            app.TodosButton = uiradiobutton(app.ButtonGroup);
            app.TodosButton.Text = 'Todos';
            app.LimparButton = uibutton(app.MquinaPVIUIFigure, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.CalcularButton = uibutton(app.MquinaPVIUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.MtodosLabel = uilabel(app.MquinaPVIUIFigure);
            app.MtodosLabel.Text = 'Métodos';
            app.func = uieditfield(app.MquinaPVIUIFigure, 'text');
            app.func.Value = 'y+exp(3*t)';
            app.ftyLabel = uilabel(app.MquinaPVIUIFigure);
            app.ftyLabel.Text = 'f(t, y)';
            app.DadosdoPVILabel = uilabel(app.MquinaPVIUIFigure);
            app.DadosdoPVILabel.Text = 'Dados do PVI';
            app.UITable = uitable(app.MquinaPVIUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.aEditFieldLabel = uilabel(app.MquinaPVIUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.a_field = uieditfield(app.MquinaPVIUIFigure, 'numeric');
            app.a_field.Value = 1;
            app.bEditFieldLabel = uilabel(app.MquinaPVIUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.b_field = uieditfield(app.MquinaPVIUIFigure, 'numeric');
            app.b_field.Value = 1.5;
            app.nEditFieldLabel = uilabel(app.MquinaPVIUIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.n_field = uieditfield(app.MquinaPVIUIFigure, 'numeric');
            app.n_field.Value = 20;
            app.y0EditFieldLabel = uilabel(app.MquinaPVIUIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0_field = uieditfield(app.MquinaPVIUIFigure, 'numeric');
            app.y0_field.Value = 2;
            app.UITable_2 = uitable(app.MquinaPVIUIFigure);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.UITable_2.Visible = 'off';
            app.UIAxes = uiaxes(app.MquinaPVIUIFigure);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.MquinaPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MquinaPVIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MquinaPVIUIFigure)
        end
    end
end